classdef my_SCARA_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        LinearinterpolationTab      matlab.ui.container.Tab
        LINEARINTERPOLATIONLabel    matlab.ui.control.Label
        StatusTextArea              matlab.ui.control.TextArea
        StatusTextAreaLabel         matlab.ui.control.Label
        IKsolveButton               matlab.ui.control.Button
        UITable2                    matlab.ui.control.Table
        DeleterowButton             matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        NewwaypointButton           matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        CircularinterpolationTab    matlab.ui.container.Tab
        CIRCULARINTERPOLATIONLabel  matlab.ui.control.Label
        SegmenttimesEditField       matlab.ui.control.NumericEditField
        SegmenttimesEditFieldLabel  matlab.ui.control.Label
        RadiusmmEditField           matlab.ui.control.NumericEditField
        RadiusmmEditFieldLabel      matlab.ui.control.Label
        NegativeinterpSwitch        matlab.ui.control.Switch
        NegativeinterpSwitchLabel   matlab.ui.control.Label
        z2EditField                 matlab.ui.control.NumericEditField
        z2EditFieldLabel            matlab.ui.control.Label
        y2EditField                 matlab.ui.control.NumericEditField
        y2EditFieldLabel            matlab.ui.control.Label
        x2EditField                 matlab.ui.control.NumericEditField
        x2EditFieldLabel            matlab.ui.control.Label
        z1EditField                 matlab.ui.control.NumericEditField
        z1EditFieldLabel            matlab.ui.control.Label
        y1EditField                 matlab.ui.control.NumericEditField
        y1EditFieldLabel            matlab.ui.control.Label
        x1EditField                 matlab.ui.control.NumericEditField
        x1EditFieldLabel            matlab.ui.control.Label
        StatusTextArea_2            matlab.ui.control.TextArea
        StatusTextArea_2Label       matlab.ui.control.Label
        IKsolveButton_2             matlab.ui.control.Button
    end

    
    properties (Access = private)
        mdl = "my_scara_control_kinematics.slx" % simulink file to load
        my_scara % robot model tree
        my_scara_info % robot model tree info

        wp_num = 0 % number of wps
        idx_to_delete = 0 

        offset_draw = 515 
    end
    
    properties (Access = public)
        q_draw % Description
        anchor_pts
        dataJoint_load
    end
    
    methods (Static)
        
        function results = load_Simscape_Data()
            %%%VariableName:smiData
            
            
            %============= RigidTransform =============%
            
            %Initialize the RigidTransform structure array by filling in null values.
            smiData.RigidTransform(12).translation = [0.0 0.0 0.0];
            smiData.RigidTransform(12).angle = 0.0;
            smiData.RigidTransform(12).axis = [0.0 0.0 0.0];
            smiData.RigidTransform(12).ID = "";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(1).translation = [350 -103.7003243291656 0];  % mm
            smiData.RigidTransform(1).angle = 2.0943951023931953;  % rad
            smiData.RigidTransform(1).axis = [0.57735026918962584 -0.57735026918962584 0.57735026918962584];
            smiData.RigidTransform(1).ID = "B[khau2_cover-1:-:lead_screw-2]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(2).translation = [-1.2462261575978624e-13 -30.008064533905213 7.6981947644887479e-14];  % mm
            smiData.RigidTransform(2).angle = 2.0943951023931953;  % rad
            smiData.RigidTransform(2).axis = [0.57735026918962584 -0.57735026918962584 0.57735026918962584];
            smiData.RigidTransform(2).ID = "F[khau2_cover-1:-:lead_screw-2]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(3).translation = [0 505.00000000000011 0];  % mm
            smiData.RigidTransform(3).angle = 2.0943951023931953;  % rad
            smiData.RigidTransform(3).axis = [-0.57735026918962584 -0.57735026918962584 -0.57735026918962584];
            smiData.RigidTransform(3).ID = "B[lead_screw-2:-:new_end_effector-1]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(4).translation = [1.9937660530126538e-13 515 1.5266746404617535e-13];  % mm
            smiData.RigidTransform(4).angle = 2.0943951023931957;  % rad
            smiData.RigidTransform(4).axis = [-0.57735026918962584 -0.57735026918962573 -0.57735026918962573];
            smiData.RigidTransform(4).ID = "F[lead_screw-2:-:new_end_effector-1]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(5).translation = [400 -125.97455967290588 0];  % mm
            smiData.RigidTransform(5).angle = 2.0943951023931953;  % rad
            smiData.RigidTransform(5).axis = [-0.57735026918962584 -0.57735026918962584 -0.57735026918962584];
            smiData.RigidTransform(5).ID = "B[khau1_arm-1:-:khau2_cover-1]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(6).translation = [7.408111556479971e-15 -207.4745596729058 -2.7439268396097297e-14];  % mm
            smiData.RigidTransform(6).angle = 2.0943951023931953;  % rad
            smiData.RigidTransform(6).axis = [-0.57735026918962584 -0.57735026918962584 -0.57735026918962584];
            smiData.RigidTransform(6).ID = "F[khau1_arm-1:-:khau2_cover-1]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(7).translation = [60.000000000000057 406.06999999999999 0];  % mm
            smiData.RigidTransform(7).angle = 2.0943951023931953;  % rad
            smiData.RigidTransform(7).axis = [-0.57735026918962584 -0.57735026918962584 -0.57735026918962584];
            smiData.RigidTransform(7).ID = "B[new_DE_2023-1:-:khau1_arm-1]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(8).translation = [-1.1184327018016837e-13 -37 -1.3834472425374859e-12];  % mm
            smiData.RigidTransform(8).angle = 2.0943951023931953;  % rad
            smiData.RigidTransform(8).axis = [-0.57735026918962584 -0.57735026918962584 -0.57735026918962584];
            smiData.RigidTransform(8).ID = "F[new_DE_2023-1:-:khau1_arm-1]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(9).translation = [0 0 0];  % mm
            smiData.RigidTransform(9).angle = 0;  % rad
            smiData.RigidTransform(9).axis = [0 0 0];
            smiData.RigidTransform(9).ID = "AssemblyGround[new_DE_2023-1:khau0_base-1]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(10).translation = [145.00000000000009 381.06999999999999 0];  % mm
            smiData.RigidTransform(10).angle = 0;  % rad
            smiData.RigidTransform(10).axis = [0 0 0];
            smiData.RigidTransform(10).ID = "AssemblyGround[new_DE_2023-1:khau0_base_connector-1]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(11).translation = [145.00000000000006 30.000000000000028 0];  % mm
            smiData.RigidTransform(11).angle = 0;  % rad
            smiData.RigidTransform(11).axis = [0 0 0];
            smiData.RigidTransform(11).ID = "AssemblyGround[new_DE_2023-1:khau0_base_support-1]";
            
            %Translation Method - Cartesian
            %Rotation Method - Arbitrary Axis
            smiData.RigidTransform(12).translation = [0 0 0];  % mm
            smiData.RigidTransform(12).angle = 0;  % rad
            smiData.RigidTransform(12).axis = [0 0 0];
            smiData.RigidTransform(12).ID = "RootGround[new_DE_2023-1]";
            
            
            %============= Solid =============%
            %Center of Mass (CoM) %Moments of Inertia (MoI) %Product of Inertia (PoI)
            
            %Initialize the Solid structure array by filling in null values.
            smiData.Solid(7).mass = 0.0;
            smiData.Solid(7).CoM = [0.0 0.0 0.0];
            smiData.Solid(7).MoI = [0.0 0.0 0.0];
            smiData.Solid(7).PoI = [0.0 0.0 0.0];
            smiData.Solid(7).color = [0.0 0.0 0.0];
            smiData.Solid(7).opacity = 0.0;
            smiData.Solid(7).ID = "";
            
            %Inertia Type - Custom
            %Visual Properties - Simple
            smiData.Solid(1).mass = 0.22695807807328738;  % kg
            smiData.Solid(1).CoM = [0 5 0];  % mm
            smiData.Solid(1).MoI = [52.95688488376706 102.13113513297934 52.95688488376706];  % kg*mm^2
            smiData.Solid(1).PoI = [0 0 0];  % kg*mm^2
            smiData.Solid(1).color = [0.77647058823529413 0.75686274509803919 0.73725490196078436];
            smiData.Solid(1).opacity = 1;
            smiData.Solid(1).ID = "new_end_effector*:*Default";
            
            %Inertia Type - Custom
            %Visual Properties - Simple
            smiData.Solid(2).mass = 9.5744060826409019;  % kg
            smiData.Solid(2).CoM = [163.72610069571357 36.926043572874498 -0.00025752436263671061];  % mm
            smiData.Solid(2).MoI = [22985.063889065816 256332.34414611544 245537.58477303636];  % kg*mm^2
            smiData.Solid(2).PoI = [0.050147274082204596 0.21679343513976457 -11428.457983066333];  % kg*mm^2
            smiData.Solid(2).color = [0.20000000000000001 0.20000000000000001 0.20000000000000001];
            smiData.Solid(2).opacity = 1;
            smiData.Solid(2).ID = "khau1_arm*:*Default";
            
            %Inertia Type - Custom
            %Visual Properties - Simple
            smiData.Solid(3).mass = 0.78900536726907722;  % kg
            smiData.Solid(3).CoM = [-0.00012093407899498624 221.14179949277639 0.00092398213247962488];  % mm
            smiData.Solid(3).MoI = [20058.00361976881 52.656671913093582 20058.001868301915];  % kg*mm^2
            smiData.Solid(3).PoI = [-0.063419069556692159 0.00060723706612499811 -0.03111362042266997];  % kg*mm^2
            smiData.Solid(3).color = [0.65098039215686276 0.61960784313725492 0.58823529411764708];
            smiData.Solid(3).opacity = 1;
            smiData.Solid(3).ID = "lead_screw*:*Default";
            
            %Inertia Type - Custom
            %Visual Properties - Simple
            smiData.Solid(4).mass = 20.914022595117437;  % kg
            smiData.Solid(4).CoM = [39.75491986204063 162.86279479346592 -2.3159223067550866e-06];  % mm
            smiData.Solid(4).MoI = [363028.60484277131 146869.752119704 403843.39906716306];  % kg*mm^2
            smiData.Solid(4).PoI = [-0.0047793892386100208 -0.001408678259642049 -35993.434211217071];  % kg*mm^2
            smiData.Solid(4).color = [0.20000000000000001 0.20000000000000001 0.20000000000000001];
            smiData.Solid(4).opacity = 1;
            smiData.Solid(4).ID = "khau0_base*:*Default";
            
            %Inertia Type - Custom
            %Visual Properties - Simple
            smiData.Solid(5).mass = 2.8785275028518575;  % kg
            smiData.Solid(5).CoM = [-108.19199372595585 14.891618632098849 0.043287992531263572];  % mm
            smiData.Solid(5).MoI = [5535.6702738932536 16613.859130838537 11785.927900301993];  % kg*mm^2
            smiData.Solid(5).PoI = [-3.0372090200302782 14.251769873493535 583.25170824856968];  % kg*mm^2
            smiData.Solid(5).color = [0.20000000000000001 0.20000000000000001 0.20000000000000001];
            smiData.Solid(5).opacity = 1;
            smiData.Solid(5).ID = "khau0_base_connector*:*Default";
            
            %Inertia Type - Custom
            %Visual Properties - Simple
            smiData.Solid(6).mass = 0.4723230430949184;  % kg
            smiData.Solid(6).CoM = [-223.50819568320952 175.48349030900434 0];  % mm
            smiData.Solid(6).MoI = [5619.4030538536708 1554.7520165220271 5632.1121769753781];  % kg*mm^2
            smiData.Solid(6).PoI = [0 0 0.10158422513146931];  % kg*mm^2
            smiData.Solid(6).color = [1 1 1];
            smiData.Solid(6).opacity = 1;
            smiData.Solid(6).ID = "khau0_base_support*:*Default";
            
            %Inertia Type - Custom
            %Visual Properties - Simple
            smiData.Solid(7).mass = 1.0601825903681412;  % kg
            smiData.Solid(7).CoM = [154.87663146170564 78.653332586573214 0.00024754457607211567];  % mm
            smiData.Solid(7).MoI = [8443.1379775268651 30633.957909966026 35252.780253868194];  % kg*mm^2
            smiData.Solid(7).PoI = [-0.0098186211144173346 0.020052134777809965 404.41140321930033];  % kg*mm^2
            smiData.Solid(7).color = [1 1 1];
            smiData.Solid(7).opacity = 1;
            smiData.Solid(7).ID = "khau2_cover*:*Default";
            
            
            %============= Joint =============%
            %X Revolute Primitive (Rx) %Y Revolute Primitive (Ry) %Z Revolute Primitive (Rz)
            %X Prismatic Primitive (Px) %Y Prismatic Primitive (Py) %Z Prismatic Primitive (Pz) %Spherical Primitive (S)
            %Constant Velocity Primitive (CV) %Lead Screw Primitive (LS)
            %Position Target (Pos)
            
            %Initialize the CylindricalJoint structure array by filling in null values.
            smiData.CylindricalJoint(1).Rz.Pos = 0.0;
            smiData.CylindricalJoint(1).Pz.Pos = 0.0;
            smiData.CylindricalJoint(1).ID = "";
            
            smiData.CylindricalJoint(1).Rz.Pos = 7.7192373210330789;  % deg
            smiData.CylindricalJoint(1).Pz.Pos = 0;  % mm
            smiData.CylindricalJoint(1).ID = "[khau2_cover-1:-:lead_screw-2]";
            
            
            %Initialize the RevoluteJoint structure array by filling in null values.
            smiData.RevoluteJoint(3).Rz.Pos = 0.0;
            smiData.RevoluteJoint(3).ID = "";
            
            smiData.RevoluteJoint(1).Rz.Pos = 55.167602202299697;  % deg
            smiData.RevoluteJoint(1).ID = "[lead_screw-2:-:new_end_effector-1]";
            
            smiData.RevoluteJoint(2).Rz.Pos = 23.252590819764258;  % deg
            smiData.RevoluteJoint(2).ID = "[khau1_arm-1:-:khau2_cover-1]";
            
            smiData.RevoluteJoint(3).Rz.Pos = -8.1439711191350863;  % deg
            smiData.RevoluteJoint(3).ID = "[new_DE_2023-1:-:khau1_arm-1]";

            results = smiData;
        end

        function [pts_lst, center, isSuccess, tSamples] = doCircularInterp(pt_from,pt_to,isNegInterp,radius,segTimePoint)
            isSuccess = true;
            
            syms x y;
            
            p1 = [pt_from(3), pt_from(1)];
            p2 = [pt_to(3), pt_to(1)];
            r = radius;
            
            midPts = [((p1(1)+p2(1))/2), ((p1(2)+p2(2))/2)];
            vec_n = [(p1(1)-p2(1)), (p1(2)-p2(2))];
            
            min_radius = sqrt((p1(1)-midPts(1))^2 + (p1(2)-midPts(2))^2);
            
            if r <= min_radius
                isSuccess = false;
                pts_lst = [];
                center = [];
            else
                % solve for center points
                eqns = [vec_n(1)*(x-midPts(1))+vec_n(2)*(y-midPts(2))==0, (x-p1(1))^2+(y-p1(2))^2==r^2];
                S = solve(eqns,[x y]);
                solutions = [double(S.x) double(S.y)];
                
                center1 = solutions(1,:);
                center2 = solutions(2,:);
                
                % check cross product
                vec_from = [p1 - center1, 0];
                vec_to = [p2 - center1, 0];
                crossProd = cross(vec_from, vec_to);
                isCross1Neg = crossProd(:,3) < 0;
            
                if xor(isCross1Neg, isNegInterp)
                    center = [center2(2), pt_from(2), center2(1)];
                    x_c = center2(1);
                    y_c = center2(2);
                    theta1 = cart2pol(p1(1)-x_c,p1(2)-y_c);
                    theta2 = cart2pol(p2(1)-x_c,p2(2)-y_c);
                    tSamples = linspace(0, segTimePoint, 1000)'; % sampling time
                    th = (tSamples/tSamples(end)) * (theta2-theta1) + theta1;
            %         if theta1 <= theta2
            %             th = (tSamples/tSamples(end)) * (theta2-theta1) + theta1;
            %         else
            %             th = (tSamples/tSamples(end)) * (theta1-theta2) + theta2;
            %         end
                    
                    xunit = r * cos(th) + x_c;
                    yunit = r * sin(th) + y_c;
                    pts_lst = [yunit, repmat(pt_from(2),length(tSamples),1), xunit];
            
                else
                    center = [center1(2), pt_from(2), center1(1)];
                    x_c = center1(1);
                    y_c = center1(2);
                    theta1 = cart2pol(p1(1)-x_c,p1(2)-y_c);
                    theta2 = cart2pol(p2(1)-x_c,p2(2)-y_c);
                    tSamples = linspace(0, segTimePoint, 1000)'; % sampling time
                    th = (tSamples/tSamples(end)) * (theta2-theta1) + theta1;
            %         if theta1 <= theta2
            %             th = (tSamples/tSamples(end)) * (theta2-theta1) + theta1;
            %         else
            %             th = (tSamples/tSamples(end)) * (theta1-theta2) + theta2;
            %         end
                    
                    xunit = r * cos(th) + x_c;
                    yunit = r * sin(th) + y_c;
                    pts_lst = [yunit, repmat(pt_from(2),length(tSamples),1), xunit];
                end
            end


            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % robot
            open_system(app.mdl);
            [app.my_scara, app.my_scara_info] = importrobot("my_scara_root");
            smiData = app.load_Simscape_Data();
            assignin("base", "smiData", smiData);

            % general const
            assignin("base", "Ts", 0.001);
            assignin("base", "degToRad_const", pi/180);
            assignin("base", "mmToM_const", 1e-3);
            assignin("base", "motor_torque_limit", 200);
            assignin("base", "motor_force_limit", 200);

            % DC motor const
            assignin("base", "upper_sat_voltage", 30);
            assignin("base", "lower_sat_voltage", 0);
            assignin("base", "upper_torque_limit", 4);
            assignin("base", "gear_ratio_j1", 10/1);
            assignin("base", "gear_ratio_j2", 10/1);
            assignin("base", "ball_screw_ratio", 2*pi*(2/1)/(10e-2));
            assignin("base", "n1", 1/80);
            assignin("base", "n2", 1/80);
            assignin("base", "n3", 10/1);
            assignin("base", "p3", 0.015);
            assignin("base", "L", 5.4e-3);
            assignin("base", "R", 1.45);
            assignin("base", "Kt", 0.957);
            assignin("base", "Ke", 0.957);
            assignin("base", "J_rotor", 125e-4);
            assignin("base", "B_motor", 6.78e-4);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close_system(app.mdl);
            
            delete(app)
            
        end

        % Button pushed function: NewwaypointButton
        function NewwaypointButtonPushed(app, event)
            app.StatusTextArea.Value = "[INFO] Added new waypoint!";
            app.wp_num = app.wp_num + 1;
            app.UITable.Data = [app.UITable.Data;
                                app.wp_num,0,0,0];
            if app.wp_num > 1
                app.UITable2.Data = [app.UITable2.Data;
                                    0];
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.wp_num = 0;
            app.UITable.Data = [];
            app.idx_to_delete = 0;
            app.UITable2.Data = [];
            app.StatusTextArea.Value = "";
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            try
                app.idx_to_delete = indices(1);
            catch
                
            end
        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            del_idx = app.idx_to_delete;
            if del_idx > 0
                try
                    app.UITable.Data(del_idx, :) = [];
                    app.UITable2.Data(end, :) = [];
                catch
                end
                size_tmp = size(app.UITable.Data);
                app.wp_num = size_tmp(1);
                if size_tmp(1) ~= 0
                    app.UITable.Data(:, 1) = linspace(1, size_tmp(1), size_tmp(1))';
                end
                app.StatusTextArea.Value = "[INFO] Deleted waypoint!";
            end
            
        end

        % Button pushed function: IKsolveButton
        function IKsolveButtonPushed(app, event)
            status_string = "[SUCCESS] Inverse Kinematics succeed!";
            
            if ~isempty(app.UITable2.Data)
                isSolution = true;
                wp_list = app.UITable.Data(:,2:end)';


                seg_time_list = app.UITable2.Data';
                seg_time_list = [seg_time_list;
                                seg_time_list;
                                seg_time_list];
                
                % generate traperzoidal trajectory (task space)
                [q,qd,qdd,tSamples,pp] = trapveltraj(wp_list,1000,"EndTime",seg_time_list);
    
                % verify traj using Inverse Kinematics
                solverParams = struct('EnforceJointLimits',false);
                ik = inverseKinematics('RigidBodyTree',app.my_scara, 'SolverAlgorithm', 'LevenbergMarquardt', 'SolverParameters', solverParams);
                weights = [0 0 0 1 1 1];
                initialguess = homeConfiguration(app.my_scara);
                endEffector = 'Body4';
                configSol_arr = zeros(3, length(q));
    
                for i = 1:length(q)
                    point = q(:,i).*(1e-03);  % convert mm to m
                
                    [configSol, ~] = ik(endEffector, trvec2tform(point'), weights, initialguess);
                
                    initialguess = configSol;
                    j1 = configSol(1).JointPosition/pi*180;
                    j2 = configSol(2).JointPosition/pi*180;
                    j3 = configSol(3).JointPosition*1e03;
                
                    % condition checking
                    if (abs(j1) > 150) || (abs(j2) > 160) || (j3 < 0) || (j3 > 280)
                        isSolution = false;
                        status_string = "[ERROR] Point [" + string(point(1)) + ...
                            ", " + string(point(2)) + ", " + string(point(3)) + "] NOT in work space";
    
                        break;
                    end
                    configSol_arr(:,i) = [configSol(1).JointPosition;configSol(2).JointPosition;configSol(3).JointPosition];
                end 

                if isSolution
                
                    app.q_draw = q';
                    app.q_draw(:,2) = app.q_draw(:,2) - app.offset_draw; % need this
                    app.anchor_pts = unique(wp_list',"rows");
                    app.anchor_pts(:,2) = app.anchor_pts(:,2) - app.offset_draw; % need this
                    
                    app.dataJoint_load = [tSamples', configSol_arr']; % need this
    
                    assignin("base", "q_draw", app.q_draw);
                    assignin("base", "anchor_pts", app.anchor_pts);
                    assignin("base", "dataJoint_load", app.dataJoint_load);
                end
                
            else
                status_string = "[ERROR] Too few Waypoints";
            end
            app.StatusTextArea.Value = status_string;
        end

        % Button pushed function: IKsolveButton_2
        function IKsolveButton_2Pushed(app, event)
            status_string = "[SUCCESS] Inverse Kinematics succeed!";
            isSolution = true;
            P1 = [app.x1EditField.Value; app.y1EditField.Value; app.z1EditField.Value];
            P2 = [app.x2EditField.Value; app.y2EditField.Value; app.z2EditField.Value];
            radius = app.RadiusmmEditField.Value;
            isNegInterp = app.NegativeinterpSwitch.Value;
            segTimePoint = app.SegmenttimesEditField.Value;  % time to travel each seg

            [pts_lst, center, isSuccess, tSamples] = doCircularInterp(P1,P2,isNegInterp,radius,segTimePoint);
            
            count = length(tSamples);
            if isSuccess
                % verify traj using Inverse Kinematics
                solverParams = struct('EnforceJointLimits',false);
                ik = inverseKinematics('RigidBodyTree',app.my_scara, 'SolverAlgorithm', 'LevenbergMarquardt', 'SolverParameters', solverParams);
                weights = [0 0 0 1 1 1];
                initialguess = homeConfiguration(app.my_scara);
                endEffector = 'Body4';
                configSol_arr = zeros(3, count);
                
                for i = 1:count
                    point = pts_lst(i,:).*(1e-03);  % convert mm to m
                
                    [configSol, ~] = ik(endEffector, trvec2tform(point), weights, initialguess);
                
                    initialguess = configSol;
                    j1 = configSol(1).JointPosition/pi*180;
                    j2 = configSol(2).JointPosition/pi*180;
                    j3 = configSol(3).JointPosition*1e03;
                
                    % condition checking
                    if (abs(j1) > 150) || (abs(j2) > 160) || (j3 < 0) || (j3 > 280)
                        isSolution = false;
                        status_string = "[ERROR] Point [" + string(point(1)) + ...
                            ", " + string(point(2)) + ", " + string(point(3)) + "] NOT in work space";
                        break;
                    end
                    configSol_arr(:,i) = [configSol(1).JointPosition;configSol(2).JointPosition;configSol(3).JointPosition];
                end 
                
                if isSolution
                
                    app.q_draw = pts_lst;
                    app.q_draw(:,2) = app.q_draw(:,2) - app.offset_draw; 

                    app.anchor_pts = [P1'; P2'; center];
                    app.anchor_pts(:,2) = app.anchor_pts(:,2) - app.offset_draw; 

                    app.dataJoint_load = [tSamples, configSol_arr']; 
    
                    assignin("base", "q_draw", app.q_draw);
                    assignin("base", "anchor_pts", app.anchor_pts);
                    assignin("base", "dataJoint_load", app.dataJoint_load);
                end
            else
                status_string = "[ERROR] Radius must be greater than distance between Point 1 and Point 2";
            end
            app.StatusTextArea_2.Value = status_string;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LinearinterpolationTab = uitab(app.TabGroup);
            app.LinearinterpolationTab.Title = 'Linear interpolation';
            app.UITable = uitable(app.LinearinterpolationTab);
            app.UITable.ColumnName = {'Waypoint number'; 'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.NewwaypointButton = uibutton(app.LinearinterpolationTab, 'push');
            app.NewwaypointButton.ButtonPushedFcn = createCallbackFcn(app, @NewwaypointButtonPushed, true);
            app.NewwaypointButton.Text = 'New waypoint';
            app.ClearButton = uibutton(app.LinearinterpolationTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DeleterowButton = uibutton(app.LinearinterpolationTab, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = 'Delete row';
            app.UITable2 = uitable(app.LinearinterpolationTab);
            app.UITable2.ColumnName = {'Seg time'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [true false];
            app.IKsolveButton = uibutton(app.LinearinterpolationTab, 'push');
            app.IKsolveButton.ButtonPushedFcn = createCallbackFcn(app, @IKsolveButtonPushed, true);
            app.IKsolveButton.Text = 'IK solve';
            app.StatusTextAreaLabel = uilabel(app.LinearinterpolationTab);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.LinearinterpolationTab);
            app.StatusTextArea.Editable = 'off';
            app.LINEARINTERPOLATIONLabel = uilabel(app.LinearinterpolationTab);
            app.LINEARINTERPOLATIONLabel.Text = 'LINEAR INTERPOLATION';
            app.CircularinterpolationTab = uitab(app.TabGroup);
            app.CircularinterpolationTab.Title = 'Circular interpolation';
            app.IKsolveButton_2 = uibutton(app.CircularinterpolationTab, 'push');
            app.IKsolveButton_2.ButtonPushedFcn = createCallbackFcn(app, @IKsolveButton_2Pushed, true);
            app.IKsolveButton_2.Text = 'IK solve';
            app.StatusTextArea_2Label = uilabel(app.CircularinterpolationTab);
            app.StatusTextArea_2Label.Text = 'Status';
            app.StatusTextArea_2 = uitextarea(app.CircularinterpolationTab);
            app.StatusTextArea_2.Editable = 'off';
            app.x1EditFieldLabel = uilabel(app.CircularinterpolationTab);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.CircularinterpolationTab, 'numeric');
            app.y1EditFieldLabel = uilabel(app.CircularinterpolationTab);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.CircularinterpolationTab, 'numeric');
            app.z1EditFieldLabel = uilabel(app.CircularinterpolationTab);
            app.z1EditFieldLabel.Text = 'z1';
            app.z1EditField = uieditfield(app.CircularinterpolationTab, 'numeric');
            app.x2EditFieldLabel = uilabel(app.CircularinterpolationTab);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.CircularinterpolationTab, 'numeric');
            app.y2EditFieldLabel = uilabel(app.CircularinterpolationTab);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.CircularinterpolationTab, 'numeric');
            app.z2EditFieldLabel = uilabel(app.CircularinterpolationTab);
            app.z2EditFieldLabel.Text = 'z2';
            app.z2EditField = uieditfield(app.CircularinterpolationTab, 'numeric');
            app.NegativeinterpSwitchLabel = uilabel(app.CircularinterpolationTab);
            app.NegativeinterpSwitchLabel.Text = 'Negative interp?';
            app.NegativeinterpSwitch = uiswitch(app.CircularinterpolationTab, 'slider');
            app.NegativeinterpSwitch.Items = {'false', 'true'};
            app.NegativeinterpSwitch.Value = 'false';
            app.RadiusmmEditFieldLabel = uilabel(app.CircularinterpolationTab);
            app.RadiusmmEditFieldLabel.Text = 'Radius (mm)';
            app.RadiusmmEditField = uieditfield(app.CircularinterpolationTab, 'numeric');
            app.SegmenttimesEditFieldLabel = uilabel(app.CircularinterpolationTab);
            app.SegmenttimesEditFieldLabel.Text = 'Segment time (s)';
            app.SegmenttimesEditField = uieditfield(app.CircularinterpolationTab, 'numeric');
            app.CIRCULARINTERPOLATIONLabel = uilabel(app.CircularinterpolationTab);
            app.CIRCULARINTERPOLATIONLabel.Text = 'CIRCULAR INTERPOLATION';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_SCARA_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end